classdef Cutting_app_9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIAxesX                    matlab.ui.control.UIAxes
        xEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        AddxyvaluepairButton       matlab.ui.control.Button
        xSlider                    matlab.ui.control.Slider
        ySlider                    matlab.ui.control.Slider
        UITable                    matlab.ui.control.Table
        SavearraytofileButton      matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        Button                     matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        MakecutsButton             matlab.ui.control.Button
        TextArea_2                 matlab.ui.control.TextArea
        TextArea_3                 matlab.ui.control.TextArea
        HelpinfoButton             matlab.ui.control.Button
        SliderX1                   matlab.ui.control.Slider
        SliderX2                   matlab.ui.control.Slider
        WidthEditFieldLabel        matlab.ui.control.Label
        WidthEditField             matlab.ui.control.NumericEditField
        VppEditFieldLabel          matlab.ui.control.Label
        VppEditField               matlab.ui.control.NumericEditField
        UIAxesY                    matlab.ui.control.UIAxes
        LengthEditFieldLabel       matlab.ui.control.Label
        LengthEditField            matlab.ui.control.NumericEditField
        SliderY1                   matlab.ui.control.Slider
        SliderY2                   matlab.ui.control.Slider
        ImportlocationarrayButton  matlab.ui.control.Button
        DeleterowButton            matlab.ui.control.Button
        CButton                    matlab.ui.control.Button
        CButton_2                  matlab.ui.control.Button
        SetmarkersizeButton        matlab.ui.control.Button
        ImportdataButton           matlab.ui.control.Button
        VppxrangeButton            matlab.ui.control.Button
        CLimEditFieldLabel         matlab.ui.control.Label
        CLimEditField              matlab.ui.control.NumericEditField
        CLim2EditField             matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        ZF = 1 % zoom factor
        Z_ON = 0
        CircleSize = 50
        Vpp_xrange = 0.5
        C_changed = 0
    end


    methods (Access = private)

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            clc;
            
            global FileName
            global D
            global ValueX2
            global ValueX3
            global OutputA;
            global OutputC;
            global plotV
            global plotH
            global plotX1
            global plotX2
            global plotX3
            global plotX4
            global plotX5
            global plotY1
            global plotY2
            global plotY3
            global ValueY2
            global ValueY3
            global DX
            global DY
            global xv
            global yv
            global xh
            global yh
            global maindir
            
            global C
            global FieldNames
            
            maindir = pwd;
            
            DX = 0;
            DY = 0;
            
            ValueX2 = 0;
            ValueX3 = 0;
            
            ValueY2 = 0;
            ValueY3 = 0;
            
            fol = uigetdir(pwd, 'Select data folder: ');
            
            folclass = class(fol);
            
            if folclass(1) == 'c'
            
                cd(fol)
                [FileName, ~, ~] = uigetfile('.mat','Select data:');
                
                app.TextArea_3.Value = 'Busy...';
                
                if size(FileName,2) > 1
                
                    C = load(FileName); %struct
                    FieldNames = fieldnames(C); %cell with field name
                    D = C.(FieldNames{1}); %just the data array
                    
                    app.UIAxes.cla;
                    
                    Xmax = size(D,2);
                    Ymax = size(D,1);
                
                    app.xSlider.Limits = [0, Xmax];
                    app.ySlider.Limits = [0, Ymax];
                    app.xSlider.MajorTicks = round(linspace(1,Xmax,5));
                    app.xSlider.MinorTicks = round(linspace(1,Xmax,25));
                    app.ySlider.MajorTicks = round(linspace(1,Ymax,5));
                    app.ySlider.MinorTicks = round(linspace(1,Ymax,25));
                    
                    app.SliderX1.Limits = [1, Xmax];
                    app.SliderX2.Limits = [1, Xmax];
                    app.SliderX1.MajorTicks = [];
                    app.SliderX2.MajorTicks = [];
                    
                    app.SliderY1.Limits = [1, Ymax];
                    app.SliderY2.Limits = [1, Ymax];
                    app.SliderY1.MajorTicks = [];
                    app.SliderY2.MajorTicks = [];
                                
                    image(app.UIAxes, D, 'CDataMapping', 'scaled');
                    colormap(app.UIAxes,'jet');
                
                    app.UIAxes.XLim = [0 Xmax];
                    app.UIAxes.YLim = [0 Ymax];
                    app.UIAxes.YDir = 'normal';
                    app.UIAxes.XTickLabel = {' '};
                    app.UIAxes.YTickLabel = {' '};
                    app.UIAxes.XLabel.String = ' ';
                    app.UIAxes.YLabel.String = ' ';
                    app.UIAxes.XTick = [1 2 3];
                    
                    app.CLimEditField.Value = app.UIAxes.CLim(1);
                    app.CLim2EditField.Value = app.UIAxes.CLim(2);
                    app.C_changed = 0;
                
                    hold(app.UIAxes, 'on')
                     
                    hold(app.UIAxesX, 'off')
                    hold(app.UIAxesY, 'off')
                    
                    tx = [1 2]; ty = [2 3];
                    plotX1 = plot(app.UIAxesX, tx, ty);
                    app.UIAxesX.XGrid = 'on';
                    app.UIAxesX.YGrid = 'on';
                    
                    plotY1 = plot(app.UIAxesY, tx, ty);
                    app.UIAxesY.XGrid = 'on';
                    app.UIAxesY.YGrid = 'on';
                    
                    hold(app.UIAxesX, 'on')
                    hold(app.UIAxesY, 'on')
                    
                    Xbot = plotX1.XData(1);
                    %Xtop = plotX1.XData(end);
                    Ybot = plotX1.YData(1);
                    Ytop = plotX1.YData(end);
                    
                    xv = [Xbot, Xbot]; 
                    yv = [Ybot, Ytop];
                    
                    plotX2 = plot(app.UIAxesX, xv,yv,'k','LineWidth',0.8);
                    plotX3 = plot(app.UIAxesX, xv+1,yv,'k','LineWidth',0.8);
                    
                    Xbot = plotY1.XData(1);
                    %Xtop = plotY1.XData(end);
                    Ybot = plotY1.YData(1);
                    Ytop = plotY1.YData(end);
                    
                    xv = [Xbot, Xbot]; 
                    yv = [Ybot, Ytop];
                    
                    plotY2 = plot(app.UIAxesY, xv,yv,'k','LineWidth',0.8);
                    plotY3 = plot(app.UIAxesY, xv+1,yv,'k','LineWidth',0.8);
                      
                    plotX4 = plot(app.UIAxesX, 1, 1, 'rx','LineWidth',1.5,'MarkerSize',8);
                    plotX5 = plot(app.UIAxesX, 1, 1, 'rx','LineWidth',1.5,'MarkerSize',8);
                    
                    hold(app.UIAxesX, 'off')
                
                    valueX = Xmax / 2;
                    xv = [valueX, valueX]; 
                    yv = [0, Ymax];
                    plotV = plot(app.UIAxes, xv,yv,'k','LineWidth',1);
                
                    valueY = Ymax / 2;
                    xh = [0, Xmax];
                    yh = [valueY, valueY];
                    plotH = plot(app.UIAxes, xh, yh,'k','LineWidth',1);
                
                    app.TextArea_3.Value = ['Import done' newline FileName];
                    app.TextArea.Value = ' ';
                    app.TextArea_2.Value = ' ';
                    app.UITable.Data = [];
                
                    OutputA = [0 0 0 0 0];
                    OutputC = [];
                    app.Z_ON = 0;
                else
                    app.TextArea_3.Value = 'No file selected';
                end
            else
                app.TextArea_3.Value = 'No folder selected';
            end


        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            global valueX                     
            global plotV
            global XMIN
            global valueXTrue
            global plotY1
            global plotY2
            global plotY3
            global YMIN
            global YPLUS
            global D
            
            valueXTrue = event.Value;
            
            if app.Z_ON == 0
                valueX = valueXTrue;
            elseif app.Z_ON == 1
                valueX = valueXTrue - XMIN;
            end
                        
            plotV.XData = [valueX valueX];
            
            app.xEditField.Value = round(event.Value);
            
            
            %% COPY PASTE:---------------------------------------------------------------------


            indX = round(valueXTrue);
                   
            
            if indX == 0
                indX = 1;
            end
            
            if app.Z_ON == 0
                D2 = D(:,indX);
                plotY1.XData = 1:size(D2,1);
            else
                D2 = D(YMIN:YPLUS,indX);
                plotY1.XData = YMIN:YPLUS;
            end
            
            plotY1.YData = D2;
            
            [Dmax, ~] = max(D2);
            [Dmin, ~] = min(D2);
            Drange = Dmax - Dmin;
            
            M = Drange * 0.15;
            Amax = Dmax + M;
            Amin = Dmin - M;
            app.UIAxesY.YLim = [Amin Amax];             
       
            %Ybot = app.UIAxesX.YLim(1);
            %Ytop = app.UIAxesX.YLim(end);
            
            plotY2.XData = [1, 1];
            plotY2.YData = [Amin, Amax];
            
            plotY3.XData = [1, 1];
            plotY3.YData = [Amin, Amax];
            
            if app.Z_ON == 0               
                app.UIAxesY.XLim = [0 size(D,1)];
            else
                app.UIAxesY.XLim = [YMIN YPLUS];
            end
            
            app.UIAxesY.XGrid = 'on';
            app.UIAxesY.YGrid = 'on';


        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            global valueY
            global plotH
            global D
            global YMIN
            global valueYTrue
            global plotX1
            global plotX2
            global plotX3
            global plotX4
            global plotX5
            global XMIN
            global XPLUS
            global Drange
            
            
            %for testing:
            %             global D1
            %             global maxloc0
            %             global minloc0
            %             global Dmid_size
            %             global Dmid
            %             global maxloc
            %             global minloc
            %             global locdiff


            valueYTrue = event.Value;
            
            if app.Z_ON == 0
                valueY = valueYTrue;
            elseif app.Z_ON == 1
                valueY = valueYTrue - YMIN;
            end
            
            plotH.YData = [valueY valueY];
            
            app.yEditField.Value = round(event.Value);
             
            indY = round(valueYTrue);
            %indY2 = round(valueYTrue);
            
            
            if indY == 0
                indY = 1;
            end
            
            if app.Z_ON == 0
                D1 = D(indY,:);
                plotX1.XData = 1:size(D1,2);
            else
                D1 = D(indY,XMIN:XPLUS);
                plotX1.XData = XMIN:XPLUS;
            end
            
            plotX1.YData = D1;
            
            minx = app.Vpp_xrange/2;
            locdiff = round(minx*size(D1,2));
            Dmid = D1(locdiff:round((1-minx)*size(D1,2)));
            Dmid_size = size(Dmid,2);
            
            [Dmax, maxloc] = max(D1);
            [Dmin, minloc] = min(D1);
            
            Drange0 = Dmax - Dmin;
            
            M = Drange0 * 0.15;
            Amax = Dmax + M;
            Amin = Dmin - M;
            app.UIAxesX.YLim = [Amin Amax];
            
            if app.Z_ON == 1
            
                [Dmax, maxloc0] = max(Dmid);
                [Dmin, minloc0] = min(Dmid);
                maxloc = maxloc0 + locdiff - 2;
                minloc = minloc0 + locdiff - 2;
            end
            
            Drange = Dmax - Dmin;
            app.VppEditField.Value = Drange; 
            
            if app.Z_ON == 0
                plotX4.XData = minloc;
                plotX5.XData = maxloc;
            else
                plotX4.XData = minloc + XMIN;
                plotX5.XData = maxloc + XMIN;
            end
            
            plotX4.YData = Dmin;            
            plotX5.YData = Dmax;
            
            if app.Z_ON == 1
                if minloc0 == 1 || minloc0 == Dmid_size
                    plotX4.Color = [0 0 1];
                else
                    plotX4.Color = [1 0 0];
                end
                
                if maxloc0 == 1 || maxloc0 == Dmid_size
                    plotX5.Color = [0 0 1];
                else
                    plotX5.Color = [1 0 0];
                end
            end


            Ybot = app.UIAxesX.YLim(1);
            Ytop = app.UIAxesX.YLim(end);
            
            plotX2.XData = [1, 1];
            plotX2.YData = [Ybot, Ytop];
            
            plotX3.XData = [1, 1];
            plotX3.YData = [Ybot, Ytop];
            
            if app.Z_ON == 0               
                app.UIAxesX.XLim = [0 size(D,2)];
            else
                app.UIAxesX.XLim = [XMIN XPLUS];
            end
            
            app.UIAxesX.XGrid = 'on';
            app.UIAxesX.YGrid = 'on';
            
        end

        % Button pushed function: AddxyvaluepairButton
        function AddxyvaluepairButtonPushed(app, event)
            global valueXTrue
            global valueYTrue
            global OutputA
            global Drange
            global DX
            global DY
            global OutputC
            
            if DX == 0 || DY == 0
                f = uifigure;
                uialert(f,'Use sliders to select length and width','Length and/or width not available');
            else
                
                OutputA = [OutputA; round(valueXTrue) round(valueYTrue) Drange DX DY];
                OutputB = sortrows(OutputA);
                OutputC = OutputB(2:end,:);
                app.UITable.Data = OutputC;
            end
            
            app.TextArea.Value = '';
            
            if app.Z_ON == 0
                px = round(valueXTrue) - DX/5;
                py = round(valueYTrue);
                plot(app.UIAxes, px, py, 'wO', 'MarkerSize', app.CircleSize, 'LineWidth', 2.5)
            end
            
        end

        % Button pushed function: SavearraytofileButton
        function SavearraytofileButtonPushed(app, event)
            global FileName
            global OutName
            global OutputC
            
            FNpart = FileName(1:7);
            OutName = strcat(FNpart, '_location_array.mat');
            
            save(OutName, 'OutputC');
            app.TextArea.Value = {'Location array saved!'};
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global valueX
            global valueY
            global D
            global XMIN
            global YMIN
            global XPLUS
            global YPLUS
            global plotV
            global plotH
            
            % for inspection:
            %             global Xmax
            %             global Ymax
            %             global XR
            %             global YR                         
            %             global ZOOMD
            
            options.Resize='on';
            options.Interpreter='tex';
            
            app.TextArea_2.Value = '';
            
            conf = inputdlg('\fontsize{10} \fontname{arial} {Zoom factor? (between 0 and 1)}', 'Zoom factor',[1 55], ...
                {' '}, options);
            
            if size(conf,1) ~= 0
                
                answer_str = conf{1};
                answer_num = str2double(answer_str);
                
                if isnan(answer_num) ~= 1
                
                    if answer_num > 0
                        if answer_num < 1
                            app.ZF = answer_num;
                        
                    
                            Xmax = size(D,2);
                            Ymax = size(D,1);
                            
                            XR = app.ZF * Xmax;
                            YR = app.ZF * Ymax;
                            
                            XMIN = round(valueX - 0.5*XR);
                            XPLUS = round(valueX + 0.5*XR);
                            if XMIN <= 1
                                XMIN = 1;
                                XPLUS = round(1 + XR);
                            elseif XPLUS >= Xmax
                                XPLUS = Xmax;
                                XMIN = round(Xmax - XR);
                            end
                            
                            YMIN = round(valueY - 0.5*YR);
                            YPLUS = round(valueY + 0.5*YR);
                            if YMIN <= 1
                                YMIN = 1;
                                YPLUS = round(1 + YR);
                            elseif YPLUS >= Ymax
                                YPLUS = Ymax;
                                YMIN = round(Ymax - YR);
                            end
                            
                            ZOOMD = D(YMIN:YPLUS, XMIN:XPLUS);
                            
                            
                            % NOW WE REPLOT OUR PLOTS: --------------------------------------------------------------------
                            
                         
                            app.xSlider.Limits = [XMIN+1, XPLUS];
                            app.ySlider.Limits = [YMIN+1, YPLUS];
                            
                            app.SliderX1.Limits = [XMIN+1, XPLUS];
                            app.SliderX2.Limits = [XMIN+1, XPLUS];
                            
                            app.SliderY1.Limits = [YMIN+1, YPLUS];
                            app.SliderY2.Limits = [YMIN+1, YPLUS];
                            
                            app.xSlider.MajorTicks = round(linspace(XMIN,XPLUS,5));
                            app.xSlider.MinorTicks = round(linspace(XMIN,XPLUS,25));
                            
                            app.ySlider.MajorTicks = round(linspace(YMIN,YPLUS,5));
                            app.ySlider.MinorTicks = round(linspace(YMIN,YPLUS,25));
                            
                            hold(app.UIAxes, 'off')
                            app.UIAxes.XLim = [1 XPLUS - XMIN];
                            app.UIAxes.YLim = [1 YPLUS - YMIN];
                            app.UIAxesX.XLim = [1 XPLUS - XMIN];
                            app.UIAxesY.XLim = [1 YPLUS - YMIN];
                            i = image(app.UIAxes, ZOOMD, 'CDataMapping', 'scaled');
                            mincol = min(min(D));
                            maxcol = max(max(D));
                            i.CData(1,1) = mincol;
                            i.CData(end,end) = maxcol;
                            colormap(app.UIAxes,'jet')
                            app.UIAxes.YDir = 'normal';
                            app.UIAxes.XTickLabel = {' '};
                            app.UIAxes.YTickLabel = {' '};
                            app.UIAxes.XLabel.String = ' ';
                            app.UIAxes.YLabel.String = ' ';
                            
                            hold(app.UIAxes, 'on')
                            
                            
                            valueX = round((XPLUS - XMIN) / 2);
                            xv = [valueX, valueX]; 
                            yv = [0, YR];
                            plotV = plot(app.UIAxes, xv,yv,'k','LineWidth',1);
                            
                            valueY = round((YPLUS - YMIN) / 2);
                            xh = [0, XR];
                            yh = [valueY, valueY];
                            plotH = plot(app.UIAxes, xh, yh,'k','LineWidth',1);
                            
                            app.Z_ON = 1;
                            
                        else
                            app.TextArea_2.Value = 'Invalid zoom factor! a';
                        end
                    else
                        app.TextArea_2.Value = 'Invalid zoom factor! b';
                    end
                else
                    app.TextArea_2.Value = 'Invalid zoom factor! c';
                end
            else
                app.TextArea_2.Value = 'Zoom cancelled';
            end


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global D
            global OutputC
            
            Xmax = size(D,2);
            Ymax = size(D,1);
            
            app.xSlider.Limits = [0, Xmax];
            app.ySlider.Limits = [0, Ymax];
            
            app.SliderX1.Limits = [0, Xmax];
            app.SliderX2.Limits = [0, Xmax];
            
            app.SliderY1.Limits = [0, Ymax];
            app.SliderY2.Limits = [0, Ymax];
            
            app.xSlider.MajorTicks = round(linspace(1,Xmax,5));
            app.xSlider.MinorTicks = round(linspace(1,Xmax,25));
            
            app.ySlider.MajorTicks = round(linspace(1,Ymax,5));
            app.ySlider.MinorTicks = round(linspace(1,Ymax,25));
            
            image(app.UIAxes, D, 'CDataMapping', 'scaled');
            app.UIAxes.XLim = [0 Xmax];
            app.UIAxes.YLim = [0 Ymax];
            colormap(app.UIAxes,'jet')
            app.UIAxes.YDir = 'normal';
            app.UIAxes.XTickLabel = {' '};
            app.UIAxes.YTickLabel = {' '};
            app.UIAxes.XLabel.String = ' ';
            app.UIAxes.YLabel.String = ' ';
            app.UIAxes.XTick = [1 2 3];
            
            hold(app.UIAxes, 'on')
            global plotV
            global plotH
            
            valueX = Xmax / 2;
            xv = [valueX, valueX]; 
            yv = [0, Ymax];
            plotV = plot(app.UIAxes, xv,yv,'k','LineWidth',1);
            
            valueY = Ymax / 2;
            xh = [0, Xmax];
            yh = [valueY, valueY];
            plotH = plot(app.UIAxes, xh, yh,'k','LineWidth',1);
            
            app.Z_ON = 0;
            
            if size(OutputC,1) > 0
                for i = 1:size(OutputC, 1)
        
                    px = OutputC(i,1) - OutputC(i,4)/5;
                    py = OutputC(i,2);
                    plot(app.UIAxes, px, py, 'wO', 'MarkerSize', app.CircleSize, 'LineWidth', 2.5)
        
                end
            end
        end

        % Button pushed function: MakecutsButton
        function MakecutsButtonPushed(app, event)
            global FileName
            global D
            global OutputA
            global xMin
            global xPlus
            global yMin
            global yPlus
            global maindir
            
            xMax = size(D,2);
            yMax = size(D,1);
             
            app.TextArea_2.Value = ' ';
            
            options.Resize='on';
            options.Interpreter='tex';
            
            %             conf = inputdlg('\fontsize{10} \fontname{arial} {Really make cuts? y/n}', 'Confirm action',[1 55], ...
            %                 {' '}, options);
            %             answer = conf{1};
            
            answer = 'y';
            
            if answer == 'y'
                                 
                FNpart = FileName(1:5);
                ArrayName = strcat(FNpart, '_location_array.mat');
                ArrayPath = strcat(pwd, '\', ArrayName);
                
                clear out_1
                
                if size(OutputA,1) > 1 %table is not empty
                    xyVP = app.UITable.Data;


                else
                    app.TextArea_2.Value = 'Cutting aborted: no xy-data';
                                            
                end
                
                if exist('xyVP','var') == 1
                    
                    options.Resize='on';
                    options.Interpreter='tex';
                    
                    out_1 = inputdlg({'\fontsize{10} \fontname{arial} x pixels','\fontsize{10} \fontname{arial} y pixels'},...
                            'Size of cut?',[1 50; 1 50],{'',''},options);


                    if size(out_1,1) ~= 0
                        
                        xPix = str2num(out_1{1}); %#ok<ST2NM>
                        yPix = str2num(out_1{2}); %#ok<ST2NM>
                    
                        tag = FileName(1:7);
                        tag2 = [tag '_cuts'];
                        tag3 = [tag2 '_' num2str(xPix) 'x' num2str(yPix)];
                        if exist(tag3, 'dir') ~= 7
                            mkdir(tag3)
                        end
                        
                        savedir = [pwd '\' tag3];
                        cd(savedir)
                        
                        tag = 'cut_images';
                        if exist(tag, 'dir') ~= 7
                            mkdir(tag)
                        end
                        
                        plotdir = [savedir '\' tag];
                        
                        warvar = 0;
                
                        w = waitbar(0, 'Saving and imaging cuts...');
                        w.Color = [1 1 0.4];
                        S = size(xyVP,1);
                        
                        for i = 1:S
                                                    
                            cd(savedir)
                            
                            Vpp_0 = xyVP(i,3);
                            Vpp = round(Vpp_0,3);
                            W_0 = xyVP(i,4);
                            W = round(W_0,1);
                            L_0 = xyVP(i,5);
                            L = round(L_0,1);
                            
                            %xCenter = xyVP(i,1);
                            % adjusting from center of blue negative peak:
                            
                            xCenter = xyVP(i,1) - round(W_0 / 4);
                            yCenter = xyVP(i,2);
                    
                            xMin = ceil(xCenter - xPix/2);
                            xPlus = ceil(xCenter + xPix/2) - 1;
                            yMin = ceil(yCenter - yPix/2);
                            yPlus = ceil(yCenter + yPix/2) - 1;
                    
                            if xMin < 1
                                xMin = 1;
                                xPlus = xPix;
                        
                            elseif xPlus > xMax
                                xPlus = xMax;
                                xMin = xMax - xPix + 1;
                            end
                    
                            if yMin < 1
                                yMin = 1;
                                yPlus = yPix;
                        
                            elseif yPlus > yMax
                                yPlus = yMax;
                                yMin = yMax - yPix + 1;
                            end
                            
                            if yMin < 1
                                yMin = 1;
                                warvar = 1;
                            elseif yPlus > yMax
                                yPlus = yMax;
                                warvar = 1;
                            end
                            
                            if xMin < 1
                                xMin = 1;
                                warvar = 1;
                            elseif xPlus > xMax
                                xPlus = xMax;
                                warvar = 1;
                            end
                    
                            CUT = D(yMin:yPlus, xMin:xPlus);
                            
                            W = abs(W);
                            L = abs(L);
                            
                            Vpp_out = sprintf('%05.3f',Vpp);
                            W_out = sprintf('%05.1f',W);
                            L_out = sprintf('%05.1f',L);
                    
                            OutName = ['cut_' num2str(i) '_Vpp=' Vpp_out '_W=' W_out '_L=' L_out '.mat'];
                            save(OutName, 'CUT');
                            
                            cd(plotdir)
                            
                            figure('Position',[455 383 624 462],'Visible','Off');
                            s1 = subplot(1,1,1);
                            image(CUT,'CDataMapping','scaled'); colormap jet;
                            s1.XLim = [0 size(CUT,2)]; s1.YDir = 'normal';
                            
                            part = OutName(1:end-4);
                            savetag = [part '.png'];        
                            saveas(gcf, savetag)
                            
                            waitbar(i/S,w)
                            p = round(100*i/S);
                            w.Name = [num2str(p) '%'];
                          
                        end
                        
                        delete(w)
                
                        cd(maindir)
                        
                        if warvar == 0
                            app.TextArea_2.Value = 'Cuts made!';
                            winopen(plotdir)
                        elseif warvar == 1
                            app.TextArea_2.Value = 'Cuts made, size adjusted';
                            winopen(plotdir)
                        else
                            app.TextArea_2.Value = 'Something went wrong';
                        end
                        
                    else
                        app.TextArea_2.Value = 'Cutting cancelled';
                    
                    end
                    
                end
                
            elseif answer == 'n'
                app.TextArea_2.Value = 'Cutting aborted';
                
            else
                app.TextArea_2.Value = 'Invalid answer!';
                
            end
            
            
        end

        % Button pushed function: HelpinfoButton
        function HelpinfoButtonPushed(app, event)
            f = uifigure;
            
            message = sprintf(['1. Global variables may cause app to malfunction, consider running "clear all" before launching app'...
                '\n \n 2. If image does not show after import, try a full MATLAB restart'...
                '\n \n 3. After zooming in our out, move main graph sliders to force side graph sliders to adjust'...
                '\n \n 4. Save functions will overwrite any files with the target name that already exist in the output folder'...
                '\n \n 5. I could not prevent automatic scaling of the bottom row buttons when enlarging the app to full screen. Sadly, '...
                'that functionality was introduced in 2017 while I used R2016b to write this app.'...
                '\n \n 6. When pressing the Vpp xrange button, the number you enter is the part of the current xrange that gets excluded '...
                'for Vpp calculation (e.g. for 0.8 only the middle 20%% of the range is used)'
                ]);
            
            uialert(f,message,'Help info','Icon','info');
                
        end

        % Value changing function: SliderX1
        function SliderX1ValueChanging(app, event)
            global ValueX2
            global ValueX3
            global plotX2
            global DX
            
            ValueX2 = event.Value;
            
            plotX2.XData = [ValueX2 ValueX2];
            
            DX = ValueX3 - ValueX2;
            app.WidthEditField.Value = DX;


        end

        % Value changing function: SliderX2
        function SliderX2ValueChanging(app, event)
            global ValueX2
            global ValueX3
            global plotX3
            global DX
            
            ValueX3 = event.Value;
            
            plotX3.XData = [ValueX3 ValueX3];
            
            DX = ValueX3 - ValueX2;
            app.WidthEditField.Value = DX;    


        end

        % Value changing function: SliderY1
        function SliderY1ValueChanging(app, event)
            global ValueY2
            global ValueY3
            global plotY2
            global DY
            
            ValueY2 = event.Value;
            
            plotY2.XData = [ValueY2 ValueY2];
            
            DY = ValueY3 - ValueY2;
            app.LengthEditField.Value = DY; 
          
        end

        % Value changing function: SliderY2
        function SliderY2ValueChanging(app, event)
            global ValueY2
            global ValueY3
            global plotY3
            global DY
            
            ValueY3 = event.Value;
            
            plotY3.XData = [ValueY3 ValueY3];
            
            DY = ValueY3 - ValueY2;
            app.LengthEditField.Value = DY; 
            
        end

        % Button pushed function: ImportlocationarrayButton
        function ImportlocationarrayButtonPushed(app, event)
            global OutputA
            global OutputC
            
            [ArrayName, ~, ~] = uigetfile('.mat','Select data:');
            C = load(ArrayName); %struct
            FieldNames = fieldnames(C); %cell with field name
            N = FieldNames{1}; %just the field name
            xyArray = getfield(C,N); %just the data array
            
            OutputC = xyArray;
            OutputA = [[0 0 0 0 0]; xyArray];
            
            app.UITable.Data = OutputC;
            
            for i = 1:size(OutputC, 1)
    
                px = OutputC(i,1) - OutputC(i,4)/5;
                py = OutputC(i,2);
                plot(app.UIAxes, px, py, 'wO', 'MarkerSize', app.CircleSize, 'LineWidth', 2.5)
    
            end
        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            global OutputA
            global OutputC
            global D
            global plotV
            global plotH
            global xv
            global yv
            global xh
            global yh
            
            options.Resize='on';
            options.Interpreter='tex';
            answer = inputdlg(['\fontsize{10.5}  '],...
                            'x value of row?',[1 50],{''},options);
            
            if size(answer,1) > 0
                
                row_x = str2double(answer{1});
                
                if isnan(row_x) == 0
                    
                    I = find(OutputC(:,1) == row_x);
                    
                    if size(I,1) == 1


                        OutputA = [0 0 0 0 0];
                        
                        for k = 1:size(OutputC,1)
                            
                            if k ~= I
                                
                                OutputA = [OutputA; OutputC(k,:)];
                                
                            end
                        end
                        
                        VXDATA = plotV.XData;
                        VYDATA = plotV.YData;
                        HXDATA = plotH.XData;
                        HYDATA = plotH.YData;
                        
                        cla(app.UIAxes)
                        image(app.UIAxes, D, 'CDataMapping', 'scaled');
                        colormap(app.UIAxes,'jet');
                        
                        plotV = plot(app.UIAxes, xv,yv,'k','LineWidth',1);
                        plotH = plot(app.UIAxes, xh, yh,'k','LineWidth',1);
                        
                        plotV.XData = VXDATA;
                        plotV.YData = VYDATA;
                        plotH.XData = HXDATA;
                        plotH.YData = HYDATA;
                        
                        OutputC = OutputA(2:end,:);
                        
                        for i = 1:size(OutputC, 1)
                    
                            px = OutputC(i,1) - OutputC(i,4)/5;
                            py = OutputC(i,2);
                            plot(app.UIAxes, px, py, 'wO', 'MarkerSize', app.CircleSize, 'LineWidth', 2.5)
                
                        end
                        
                        app.UITable.Data = OutputC;
                        
                        app.TextArea.Value = 'row deleted';
                        
                    else
                        app.TextArea.Value = 'x value must be in the table!';
                    end
                else
                    app.TextArea.Value = 'x value must be numeric!';
                end
            else
                app.TextArea.Value = 'row deletion canceled';
            end


        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            global D
            global plotV
            global plotH
            global xv
            global yv
            global xh
            global yh
            global OutputC
            
            if app.C_changed == 0
                
                Dmax = max(max(D));
                Dmin = min(min(D));
                
                if D(1,1) == Dmax
                   D(1,1) = 0.9 * Dmax;
                else
                    D(end,end) = 1.1*Dmin;
                end
                
                VXDATA = plotV.XData;
                VYDATA = plotV.YData;
                HXDATA = plotH.XData;
                HYDATA = plotH.YData;
                
                cla(app.UIAxes)
                image(app.UIAxes, D, 'CDataMapping', 'scaled');
                colormap(app.UIAxes,'jet');
                
                app.CLimEditField.Value = app.UIAxes.CLim(1);
                app.CLim2EditField.Value = app.UIAxes.CLim(2);
                
                plotV = plot(app.UIAxes, xv,yv,'k','LineWidth',1);
                plotH = plot(app.UIAxes, xh, yh,'k','LineWidth',1);
                
                plotV.XData = VXDATA;
                plotV.YData = VYDATA;
                plotH.XData = HXDATA;
                plotH.YData = HYDATA;
                
                if size(OutputC,1) > 0
                    for i = 1:size(OutputC, 1)
            
                        px = OutputC(i,1) - OutputC(i,4)/5;
                        py = OutputC(i,2);
                        plot(app.UIAxes, px, py, 'wO', 'MarkerSize', app.CircleSize, 'LineWidth', 2.5)
            
                    end
                end
            else
                app.TextArea_2.Value = 'Error: cannot use [+] after CLim was manually changed, use [C reset] to reenable';
            end
        end

        % Button pushed function: CButton_2
        function CButton_2Pushed(app, event)
            global D
            global plotV
            global plotH
            global xv
            global yv
            global xh
            global yh
            global OutputC
            
            if app.C_changed == 0
                
                Dmax = max(max(D));
                Dmin = min(min(D));
                
                if D(end,end) == Dmin
                    D(end,end) = 0.9 * Dmin;
                else
                    D(1,1) = 1.1 * Dmax;
                end
                
                VXDATA = plotV.XData;
                VYDATA = plotV.YData;
                HXDATA = plotH.XData;
                HYDATA = plotH.YData;
                
                cla(app.UIAxes)
                image(app.UIAxes, D, 'CDataMapping', 'scaled');
                colormap(app.UIAxes,'jet');
                
                app.CLimEditField.Value = app.UIAxes.CLim(1);
                app.CLim2EditField.Value = app.UIAxes.CLim(2);
                
                plotV = plot(app.UIAxes, xv,yv,'k','LineWidth',1);
                plotH = plot(app.UIAxes, xh, yh,'k','LineWidth',1);
                
                plotV.XData = VXDATA;
                plotV.YData = VYDATA;
                plotH.XData = HXDATA;
                plotH.YData = HYDATA;
                
                if size(OutputC,1) > 0
                    for i = 1:size(OutputC, 1)
            
                        px = OutputC(i,1) - OutputC(i,4)/5;
                        py = OutputC(i,2);
                        plot(app.UIAxes, px, py, 'wO', 'MarkerSize', app.CircleSize, 'LineWidth', 2.5)
            
                    end
                end
            else
                app.TextArea_2.Value = 'Error: cannot use [-] after CLim was manually changed, use [C reset] to reenable';
            end
        end

        % Button pushed function: SetmarkersizeButton
        function SetmarkersizeButtonPushed(app, event)
            global D
            global plotV
            global plotH
            global xv
            global yv
            global xh
            global yh
            global OutputC
            
            options.Resize='on';
            options.Interpreter='tex';
                    
            answer = inputdlg(['\fontsize{10.5} (current size is ' num2str(app.CircleSize) ')'],...
                            'Size of circle markers?',[1 50],{''},options);
            
            if size(answer,1) > 0
                
                PrevSize = app.CircleSize;
                app.CircleSize = str2double(answer{1});
                
                if isnan(app.CircleSize) == 0
                    
                    if app.CircleSize > 0
                
                        VXDATA = plotV.XData;
                        VYDATA = plotV.YData;
                        HXDATA = plotH.XData;
                        HYDATA = plotH.YData;
                        
                        cla(app.UIAxes)
                        image(app.UIAxes, D, 'CDataMapping', 'scaled');
                        colormap(app.UIAxes,'jet');
                        
                        plotV = plot(app.UIAxes, xv,yv,'k','LineWidth',1);
                        plotH = plot(app.UIAxes, xh, yh,'k','LineWidth',1);
                        
                        plotV.XData = VXDATA;
                        plotV.YData = VYDATA;
                        plotH.XData = HXDATA;
                        plotH.YData = HYDATA;
                        
                        if size(OutputC,1) > 0
                            for i = 1:size(OutputC, 1)
                    
                                px = OutputC(i,1) - OutputC(i,4)/5;
                                py = OutputC(i,2);
                                plot(app.UIAxes, px, py, 'wO', 'MarkerSize', app.CircleSize, 'LineWidth', 2.5)
                    
                            end
                        end
                        
                        app.TextArea.Value = '';
                    else
                        app.TextArea.Value = 'MarkerSize must be greater than zero!';
                        app.CircleSize = PrevSize;
                    end
                    
                else
                    app.TextArea.Value = 'MarkerSize must be numeric!';
                    app.CircleSize = PrevSize;
                end
            else
                app.TextArea.Value = 'No marker size selected!';
            end
        end

        % Button pushed function: VppxrangeButton
        function VppxrangeButtonPushed(app, event)
            options.Resize='on';
            options.Interpreter='tex';
            
            answer0 = inputdlg(['\fontsize{10.5} (number between 0 and 1)'],...
                            'X-range for Vpp calculation?',[1 50],{''},options);
                        
            answer1 = answer0{1};
            answer2 = str2double(answer1);
            
            if isnan(answer2) == 1
                app.TextArea.Value = 'Invalid xrange: must be a number!';
            else
                if answer2 <= 0 || answer2 >= 1
                    app.TextArea.Value = 'Invalid xrange size!';
                else
                    app.Vpp_xrange = answer2;
                end
            end


        end

        % Value changed function: CLimEditField
        function CLimEditFieldValueChanged(app, event)
            value = app.CLimEditField.Value;
            
            C2 = app.UIAxes.CLim(2);
            app.UIAxes.CLim = [value C2];
            app.C_changed = 1;
            
        end

        % Value changed function: CLim2EditField
        function CLim2EditFieldValueChanged(app, event)
            value = app.CLim2EditField.Value;
            
            C1 = app.UIAxes.CLim(1);
            app.UIAxes.CLim = [C1 value];
            app.C_changed = 1;
            
        end

        % Callback function
        function CresetButtonPushed(app, event)
            global FileName
            global D
            global ValueX2
            global ValueX3
            global OutputA;
            global OutputC;
            global plotV
            global plotH
            global plotX1
            global plotX2
            global plotX3
            global plotX4
            global plotX5
            global plotY1
            global plotY2
            global plotY3
            global ValueY2
            global ValueY3
            global DX
            global DY
            global xv
            global yv
            global xh
            global yh
            global maindir    
            
            C = load(FileName); 
            FieldNames = fieldnames(C);
            D = C.(FieldNames{1});
            
            app.UIAxes.cla;
            app.UIAxes.CLim = [0 0.01];
            
            Xmax = size(D,2);
            Ymax = size(D,1);
        
            app.xSlider.Limits = [0, Xmax];
            app.ySlider.Limits = [0, Ymax];
            app.xSlider.MajorTicks = round(linspace(1,Xmax,5));
            app.xSlider.MinorTicks = round(linspace(1,Xmax,25));
            app.ySlider.MajorTicks = round(linspace(1,Ymax,5));
            app.ySlider.MinorTicks = round(linspace(1,Ymax,25));
            
            app.SliderX1.Limits = [1, Xmax];
            app.SliderX2.Limits = [1, Xmax];
            app.SliderX1.MajorTicks = [];
            app.SliderX2.MajorTicks = [];
            
            app.SliderY1.Limits = [1, Ymax];
            app.SliderY2.Limits = [1, Ymax];
            app.SliderY1.MajorTicks = [];
            app.SliderY2.MajorTicks = [];
                        
            image(app.UIAxes, D, 'CDataMapping', 'scaled');
            colormap(app.UIAxes,'jet');
        
            app.UIAxes.XLim = [0 Xmax];
            app.UIAxes.YLim = [0 Ymax];
            app.UIAxes.YDir = 'normal';
            app.UIAxes.XTickLabel = {' '};
            app.UIAxes.YTickLabel = {' '};
            app.UIAxes.XLabel.String = ' ';
            app.UIAxes.YLabel.String = ' ';
            app.UIAxes.XTick = [1 2 3];
            
            app.CLimEditField.Value = app.UIAxes.CLim(1);
            app.CLim2EditField.Value = app.UIAxes.CLim(2);
            app.C_changed = 0;
        
            hold(app.UIAxes, 'on')
             
            hold(app.UIAxesX, 'off')
            hold(app.UIAxesY, 'off')
            
            tx = [1 2]; ty = [2 3];
            plotX1 = plot(app.UIAxesX, tx, ty);
            app.UIAxesX.XGrid = 'on';
            app.UIAxesX.YGrid = 'on';
            
            plotY1 = plot(app.UIAxesY, tx, ty);
            app.UIAxesY.XGrid = 'on';
            app.UIAxesY.YGrid = 'on';
            
            hold(app.UIAxesX, 'on')
            hold(app.UIAxesY, 'on')
            
            Xbot = plotX1.XData(1);
            %Xtop = plotX1.XData(end);
            Ybot = plotX1.YData(1);
            Ytop = plotX1.YData(end);
            
            xv = [Xbot, Xbot]; 
            yv = [Ybot, Ytop];
            
            plotX2 = plot(app.UIAxesX, xv,yv,'k','LineWidth',0.8);
            plotX3 = plot(app.UIAxesX, xv+1,yv,'k','LineWidth',0.8);
            
            Xbot = plotY1.XData(1);
            %Xtop = plotY1.XData(end);
            Ybot = plotY1.YData(1);
            Ytop = plotY1.YData(end);
            
            xv = [Xbot, Xbot]; 
            yv = [Ybot, Ytop];
                
            plotY2 = plot(app.UIAxesY, xv,yv,'k','LineWidth',0.8);
            plotY3 = plot(app.UIAxesY, xv+1,yv,'k','LineWidth',0.8);
              
            plotX4 = plot(app.UIAxesX, 1, 1, 'rx','LineWidth',1.5,'MarkerSize',8);
            plotX5 = plot(app.UIAxesX, 1, 1, 'rx','LineWidth',1.5,'MarkerSize',8);
            
            hold(app.UIAxesX, 'off')
        
            valueX = Xmax / 2;
            xv = [valueX, valueX]; 
            yv = [0, Ymax];
            plotV = plot(app.UIAxes, xv,yv,'k','LineWidth',1);
        
            valueY = Ymax / 2;
            xh = [0, Xmax];
            yh = [valueY, valueY];
            plotH = plot(app.UIAxes, xh, yh,'k','LineWidth',1);
            
            if size(OutputC,1) > 0
                for i = 1:size(OutputC, 1)
        
                    px = OutputC(i,1) - OutputC(i,4)/5;
                    py = OutputC(i,2);
                    plot(app.UIAxes, px, py, 'wO', 'MarkerSize', app.CircleSize, 'LineWidth', 2.5)
        
                end
            end
            
            app.TextArea_2.Value = '';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxesX = uiaxes(app.UIFigure);
            title(app.UIAxesX, 'X cut');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddxyvaluepairButton = uibutton(app.UIFigure, 'push');
            app.AddxyvaluepairButton.ButtonPushedFcn = createCallbackFcn(app, @AddxyvaluepairButtonPushed, true);
            app.AddxyvaluepairButton.Text = 'Add (x,y) value pair';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.MajorTicks = [0 20 40 60 80 100];
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.MajorTicks = [0 20 40 60 80 100];
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'; 'Vpp'; 'Width'; 'Length'};
            app.UITable.RowName = {};
            app.SavearraytofileButton = uibutton(app.UIFigure, 'push');
            app.SavearraytofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavearraytofileButtonPushed, true);
            app.SavearraytofileButton.Text = 'Save array to file';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.MakecutsButton = uibutton(app.UIFigure, 'push');
            app.MakecutsButton.ButtonPushedFcn = createCallbackFcn(app, @MakecutsButtonPushed, true);
            app.MakecutsButton.Text = 'Make cuts';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.HelpinfoButton = uibutton(app.UIFigure, 'push');
            app.HelpinfoButton.ButtonPushedFcn = createCallbackFcn(app, @HelpinfoButtonPushed, true);
            app.HelpinfoButton.Text = 'Help / info';
            app.SliderX1 = uislider(app.UIFigure);
            app.SliderX1.MajorTicks = [0 100];
            app.SliderX1.ValueChangingFcn = createCallbackFcn(app, @SliderX1ValueChanging, true);
            app.SliderX1.MinorTicks = [];
            app.SliderX2 = uislider(app.UIFigure);
            app.SliderX2.MajorTicks = [0 100];
            app.SliderX2.ValueChangingFcn = createCallbackFcn(app, @SliderX2ValueChanging, true);
            app.SliderX2.MinorTicks = [];
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.VppEditFieldLabel = uilabel(app.UIFigure);
            app.VppEditFieldLabel.Text = 'Vpp';
            app.VppEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxesY = uiaxes(app.UIFigure);
            title(app.UIAxesY, 'Y cut');
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SliderY1 = uislider(app.UIFigure);
            app.SliderY1.MajorTicks = [0 100];
            app.SliderY1.ValueChangingFcn = createCallbackFcn(app, @SliderY1ValueChanging, true);
            app.SliderY1.MinorTicks = [];
            app.SliderY2 = uislider(app.UIFigure);
            app.SliderY2.MajorTicks = [0 100];
            app.SliderY2.ValueChangingFcn = createCallbackFcn(app, @SliderY2ValueChanging, true);
            app.SliderY2.MinorTicks = [];
            app.ImportlocationarrayButton = uibutton(app.UIFigure, 'push');
            app.ImportlocationarrayButton.ButtonPushedFcn = createCallbackFcn(app, @ImportlocationarrayButtonPushed, true);
            app.ImportlocationarrayButton.Text = 'Import location array';
            app.DeleterowButton = uibutton(app.UIFigure, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = 'Delete row';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C+';
            app.CButton_2 = uibutton(app.UIFigure, 'push');
            app.CButton_2.ButtonPushedFcn = createCallbackFcn(app, @CButton_2Pushed, true);
            app.CButton_2.Text = 'C-';
            app.SetmarkersizeButton = uibutton(app.UIFigure, 'push');
            app.SetmarkersizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetmarkersizeButtonPushed, true);
            app.SetmarkersizeButton.Text = 'Set marker size';
            app.ImportdataButton = uibutton(app.UIFigure, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.VppxrangeButton = uibutton(app.UIFigure, 'push');
            app.VppxrangeButton.ButtonPushedFcn = createCallbackFcn(app, @VppxrangeButtonPushed, true);
            app.VppxrangeButton.Text = 'Vpp xrange';
            app.CLimEditFieldLabel = uilabel(app.UIFigure);
            app.CLimEditFieldLabel.Text = 'CLim';
            app.CLimEditField = uieditfield(app.UIFigure, 'numeric');
            app.CLimEditField.ValueChangedFcn = createCallbackFcn(app, @CLimEditFieldValueChanged, true);
            app.CLim2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CLim2EditField.ValueChangedFcn = createCallbackFcn(app, @CLim2EditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Cutting_app_9()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end