classdef background_selection_app_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        xEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        AddxyvaluepairButton       matlab.ui.control.Button
        xSlider                    matlab.ui.control.Slider
        ySlider                    matlab.ui.control.Slider
        UITable                    matlab.ui.control.Table
        SavearraytofileButton      matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        TextArea_3                 matlab.ui.control.TextArea
        ImportlocationarrayButton  matlab.ui.control.Button
        DeleterowButton            matlab.ui.control.Button
        CButton                    matlab.ui.control.Button
        CButton_2                  matlab.ui.control.Button
        SetlinewidthButton         matlab.ui.control.Button
        ImportdataButton           matlab.ui.control.Button
        CLimEditFieldLabel         matlab.ui.control.Label
        CLimEditField              matlab.ui.control.NumericEditField
        CLim2EditField             matlab.ui.control.NumericEditField
        CresetButton               matlab.ui.control.Button
        SetboxsizeButton           matlab.ui.control.Button
        BoxsizexEditFieldLabel     matlab.ui.control.Label
        BoxsizexEditField          matlab.ui.control.NumericEditField
        BoxsizeyEditFieldLabel     matlab.ui.control.Label
        BoxsizeyEditField          matlab.ui.control.NumericEditField
        BoxcolorButtonGroup        matlab.ui.container.ButtonGroup
        WhiteButton                matlab.ui.control.RadioButton
        BlackButton                matlab.ui.control.RadioButton
    end


    properties (Access = private)
        ZF = 1 
        Z_ON = 0
        LineSize = 3
        LineSize2 = 1.5
        Vpp_xrange = 0.5
        XBoxSize = 150
        YBoxSize = 450
        CLimStart = [0,0]
        BoxColor = [1,1,1];
    end


    methods (Access = private)

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
            clc;
            
            global FileName
            global D
            global ValueX2
            global ValueX3
            global OutputA;
            global OutputC;
            global ValueY2
            global ValueY3
            global DX
            global DY
            global maindir
            
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            
            maindir = pwd;
            
            DX = 0;
            DY = 0;
            
            ValueX2 = 0;
            ValueX3 = 0;
            
            ValueY2 = 0;
            ValueY3 = 0;
            
            fol = uigetdir(pwd, 'Select data folder: ');
            cd(fol)
            [FileName, ~, ~] = uigetfile('.mat','Select data:');
            
            app.TextArea_3.Value = 'Busy...';
            
            if size(FileName,2) > 1
            
                C = load(FileName); % struct
                FieldNames = fieldnames(C); % cell with field name
                D = C.(FieldNames{1}); % data array
                
                app.UIAxes.cla;
                
                Xmax = size(D,2);
                Ymax = size(D,1);
            
                app.xSlider.Limits = [round(app.XBoxSize/2), round(Xmax-app.XBoxSize/2)];
                app.ySlider.Limits = [round(app.YBoxSize/2), round(Ymax-app.YBoxSize/2)];
                app.xSlider.MajorTicks = round(linspace(app.XBoxSize, Xmax-app.XBoxSize,5));
                app.xSlider.MinorTicks = round(linspace(app.XBoxSize, Xmax-app.XBoxSize,25));
                app.ySlider.MajorTicks = round(linspace(app.YBoxSize, Ymax-app.YBoxSize,5));
                app.ySlider.MinorTicks = round(linspace(app.YBoxSize, Ymax-app.YBoxSize,25));  
                
                app.UIAxes.CLimMode = 'auto';
                image(app.UIAxes, D, 'CDataMapping', 'scaled');
                colormap(app.UIAxes,'jet');
            
                app.UIAxes.XLim = [0 Xmax];
                app.UIAxes.YLim = [0 Ymax];
                app.UIAxes.YDir = 'normal';
                app.UIAxes.XTickLabel = {' '};
                app.UIAxes.YTickLabel = {' '};
                app.UIAxes.XLabel.String = ' ';
                app.UIAxes.YLabel.String = ' ';
                app.UIAxes.XTick = [1 2 3];
                
                app.CLimEditField.Value = app.UIAxes.CLim(1);
                app.CLim2EditField.Value = app.UIAxes.CLim(2);
                app.CLimStart = app.UIAxes.CLim;
            
                hold(app.UIAxes, 'on')
            
                VX1 = round(Xmax / 2 - app.XBoxSize / 2);
                VX2 = round(Xmax / 2 + app.XBoxSize / 2);
                
                VY1 = round(Ymax / 2 - app.YBoxSize / 2);
                VY2 = round(Ymax / 2 + app.YBoxSize / 2);
                
                xr1 = [VX1, VX1]; 
                xr2 = [VX2, VX2];
                xrV = [VX1, VX2];
                
                yr1 = [VY1, VY1];
                yr2 = [VY2, VY2];
                yrV = [VY1, VY2];                
                
                plotV1 = plot(app.UIAxes, xr1,yrV,'LineWidth',app.LineSize);
                plotV2 = plot(app.UIAxes, xr2,yrV,'LineWidth',app.LineSize);
                plotH1 = plot(app.UIAxes, xrV,yr1,'LineWidth',app.LineSize);
                plotH2 = plot(app.UIAxes, xrV,yr2,'LineWidth',app.LineSize);
                
                plotV1.Color = app.BoxColor;
                plotV2.Color = app.BoxColor;
                plotH1.Color = app.BoxColor;
                plotH2.Color = app.BoxColor;
            
                app.TextArea_3.Value = ['Import done' newline FileName];
                app.TextArea.Value = ' ';
                app.UITable.Data = [];
                
                app.BoxsizexEditField.Value = app.XBoxSize;
                app.BoxsizeyEditField.Value = app.YBoxSize;
            
                OutputA = [0 0];
                OutputC = [];
                app.Z_ON = 0;
            else
                app.TextArea_3.Value = 'No file selected';
            end


        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            global valueX                     
            global XMIN
            global valueXTrue
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            global Xleft
            global Xright
            
            valueXTrue = event.Value;
            
            if app.Z_ON == 0
                valueX = valueXTrue;
            elseif app.Z_ON == 1
                valueX = valueXTrue - XMIN;
            end
            
            app.xEditField.Value = round(event.Value);
            
            Xleft = round(valueXTrue - app.XBoxSize / 2);
            Xright = round(valueXTrue + app.XBoxSize / 2);
            
            plotV1.XData = [Xleft, Xleft];
            plotV2.XData = [Xright, Xright];
            
            plotH1.XData = [Xleft, Xright];
            plotH2.XData = [Xleft, Xright];
                                             
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            global valueY
            global YMIN
            global valueYTrue
            global plotV1
            global plotV2
            global plotH1
            global plotH2 
            global Ybot
            global Ytop
            
            valueYTrue = event.Value;
            
            if app.Z_ON == 0
                valueY = valueYTrue;
            elseif app.Z_ON == 1
                valueY = valueYTrue - YMIN;
            end
                        
            app.yEditField.Value = round(event.Value);
             
            Ybot = round(valueYTrue - app.YBoxSize / 2);
            Ytop = round(valueYTrue + app.YBoxSize / 2);
            
            plotH1.YData = [Ybot, Ybot];
            plotH2.YData = [Ytop, Ytop];
            
            plotV1.YData = [Ybot, Ytop];
            plotV2.YData = [Ybot, Ytop];
             
        end

        % Button pushed function: AddxyvaluepairButton
        function AddxyvaluepairButtonPushed(app, event)
            global valueXTrue
            global valueYTrue
            global OutputA
            global OutputC
            global Xleft
            global Xright
            global Ybot
            global Ytop
                
            OutputA = [OutputA; round(valueXTrue) round(valueYTrue)];
            OutputB = sortrows(OutputA);
            OutputC = OutputB(2:end,:);
            app.UITable.Data = OutputC;
            
            app.TextArea.Value = '';
            
            XS1 = [Xleft, Xleft];
            XS2 = [Xright, Xright];
            
            YS1 = [Ybot, Ybot];
            YS2 = [Ytop, Ytop];
            
            XR = [Xleft, Xright];
            YR = [Ybot, Ytop];
            
            if app.Z_ON == 0
                pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                pt.Color = app.BoxColor;
                pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                pt.Color = app.BoxColor;
                pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                pt.Color = app.BoxColor;
                pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                pt.Color = app.BoxColor;
            end
            
        end

        % Button pushed function: SavearraytofileButton
        function SavearraytofileButtonPushed(app, event)
            global FileName
            global OutName
            global OutputC
            
            FNpart = FileName(1:7);
            OutName = strcat(FNpart, '_background_array.mat');
            
            save(OutName, 'OutputC');
            app.TextArea.Value = {'Location array saved!'};
        end

        % Callback function
        function ButtonPushed(app, event)
            global valueX
            global valueY
            global D
            global XMIN
            global YMIN
            global XPLUS
            global YPLUS
            
            options.Resize='on';
            options.Interpreter='tex';
            
            app.TextArea.Value = '';
            
            conf = inputdlg('\fontsize{10} \fontname{arial} {Zoom factor? (between 0 and 1)}', 'Zoom factor',[1 55], ...
                {' '}, options);
            
            if size(conf,1) ~= 0
                
                answer_str = conf{1};
                answer_num = str2double(answer_str);
                
                if isnan(answer_num) ~= 1
                
                    if answer_num > 0
                        if answer_num < 1
                            app.ZF = answer_num;
                        
                    
                            Xmax = size(D,2);
                            Ymax = size(D,1);
                            
                            XR = app.ZF * Xmax;
                            YR = app.ZF * Ymax;
                            
                            XMIN = round(valueX - 0.5*XR);
                            XPLUS = round(valueX + 0.5*XR);
                            if XMIN <= 1
                                XMIN = 1;
                                XPLUS = round(1 + XR);
                            elseif XPLUS >= Xmax
                                XPLUS = Xmax;
                                XMIN = round(Xmax - XR);
                            end
                            
                            YMIN = round(valueY - 0.5*YR);
                            YPLUS = round(valueY + 0.5*YR);
                            if YMIN <= 1
                                YMIN = 1;
                                YPLUS = round(1 + YR);
                            elseif YPLUS >= Ymax
                                YPLUS = Ymax;
                                YMIN = round(Ymax - YR);
                            end
                            
                            ZOOMD = D(YMIN:YPLUS, XMIN:XPLUS);
                            
                            
                            % NOW WE REPLOT OUR PLOTS: --------------------------------------------------------------------
                            
                         
                            app.xSlider.Limits = [XMIN+1, XPLUS];
                            app.ySlider.Limits = [YMIN+1, YPLUS];
                            
                            app.xSlider.MajorTicks = round(linspace(XMIN,XPLUS,5));
                            app.xSlider.MinorTicks = round(linspace(XMIN,XPLUS,25));
                            
                            app.ySlider.MajorTicks = round(linspace(YMIN,YPLUS,5));
                            app.ySlider.MinorTicks = round(linspace(YMIN,YPLUS,25));
                            
                            hold(app.UIAxes, 'off')
                            app.UIAxes.XLim = [1 XPLUS - XMIN];
                            app.UIAxes.YLim = [1 YPLUS - YMIN];
                            i = image(app.UIAxes, ZOOMD, 'CDataMapping', 'scaled');
                            mincol = min(min(D));
                            maxcol = max(max(D));
                            i.CData(1,1) = mincol;
                            i.CData(end,end) = maxcol;
                            colormap(app.UIAxes,'jet')
                            app.UIAxes.YDir = 'normal';
                            app.UIAxes.XTickLabel = {' '};
                            app.UIAxes.YTickLabel = {' '};
                            app.UIAxes.XLabel.String = ' ';
                            app.UIAxes.YLabel.String = ' ';
                            
                            hold(app.UIAxes, 'on')
                            
                            app.Z_ON = 1;
                            
                        else
                            app.TextArea.Value = 'Invalid zoom factor! a';
                        end
                    else
                        app.TextArea.Value = 'Invalid zoom factor! b';
                    end
                else
                    app.TextArea.Value = 'Invalid zoom factor! c';
                end
            else
                app.TextArea.Value = 'Zoom cancelled';
            end
               
        end

        % Callback function
        function Button_2Pushed(app, event)
            global D
            global OutputC
            
            Xmax = size(D,2);
            Ymax = size(D,1);
            
            app.xSlider.Limits = [0, Xmax];
            app.ySlider.Limits = [0, Ymax];
            
            app.xSlider.MajorTicks = round(linspace(1,Xmax,5));
            app.xSlider.MinorTicks = round(linspace(1,Xmax,25));
            
            app.ySlider.MajorTicks = round(linspace(1,Ymax,5));
            app.ySlider.MinorTicks = round(linspace(1,Ymax,25));
            
            image(app.UIAxes, D, 'CDataMapping', 'scaled');
            app.UIAxes.XLim = [0 Xmax];
            app.UIAxes.YLim = [0 Ymax];
            colormap(app.UIAxes,'jet')
            app.UIAxes.YDir = 'normal';
            app.UIAxes.XTickLabel = {' '};
            app.UIAxes.YTickLabel = {' '};
            app.UIAxes.XLabel.String = ' ';
            app.UIAxes.YLabel.String = ' ';
            app.UIAxes.XTick = [1 2 3];
            
            hold(app.UIAxes, 'on')
                       
            app.Z_ON = 0;
            
            if size(OutputC,1) > 0
                for i = 1:size(OutputC, 1)
                    
                    valueXTrue = OutputC(i,1);
                    valueYTrue = OutputC(i,2);
        
                    XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                    XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                    
                    YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                    YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                    
                    XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                    YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
            
                    pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
            
        
                end
            end
        end

        % Callback function
        function MakecutsButtonPushed(app, event)
            
        end

        % Callback function
        function HelpinfoButtonPushed(app, event)
              
        end

        % Callback function
        function SliderX1ValueChanging(app, event)
            
        end

        % Callback function
        function SliderX2ValueChanging(app, event)
            
        end

        % Callback function
        function SliderY1ValueChanging(app, event)
          
        end

        % Callback function
        function SliderY2ValueChanging(app, event)
            
        end

        % Button pushed function: ImportlocationarrayButton
        function ImportlocationarrayButtonPushed(app, event)
            global OutputA
            global OutputC
            
            [ArrayName, ~, ~] = uigetfile('.mat','Select data:');
            C = load(ArrayName); %struct
            FieldNames = fieldnames(C); %cell with field name
            xyArray = C.(FieldNames{1}); %just the data array
            
            OutputC = xyArray;
            OutputA = [[0 0]; xyArray];
            
            app.UITable.Data = OutputC;
            
            if size(OutputC,1) > 0
                for i = 1:size(OutputC, 1)
                    
                    valueXTrue = OutputC(i,1);
                    valueYTrue = OutputC(i,2);
        
                    XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                    XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                    
                    YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                    YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                    
                    XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                    YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
            
                    pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
            
        
                end
            end
        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            global OutputA
            global OutputC
            global D
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            
            options.Resize='on';
            options.Interpreter='tex';
            answer = inputdlg(['\fontsize{10.5}  '],...
                            'x value of row?',[1 50],{''},options);
            
            if size(answer,1) > 0
                
                row_x = str2double(answer{1});
                
                if isnan(row_x) == 0
                    
                    I = find(OutputC(:,1) == row_x);
                    
                    if size(I,1) == 1


                        OutputA = [0 0];
                        
                        for k = 1:size(OutputC,1)
                            
                            if k ~= I
                                
                                OutputA = [OutputA; OutputC(k,:)];
                                
                            end
                        end
                        
                        V1XDATA = plotV1.XData;
                        V2XDATA = plotV2.XData;
                        V1YDATA = plotV1.YData;
                        V2YDATA = plotV2.YData;
                        H1XDATA = plotH1.XData;
                        H2XDATA = plotH2.XData;
                        H1YDATA = plotH1.YData;
                        H2YDATA = plotH2.YData;
                        
                        cla(app.UIAxes)
                        image(app.UIAxes, D, 'CDataMapping', 'scaled');
                        colormap(app.UIAxes,'jet');
                        
                        plotV1 = plot(app.UIAxes, V1XDATA, V1YDATA,'LineWidth',app.LineSize);
                        plotV2 = plot(app.UIAxes, V2XDATA, V2YDATA,'LineWidth',app.LineSize);
                        plotH1 = plot(app.UIAxes, H1XDATA, H1YDATA,'LineWidth',app.LineSize);
                        plotH2 = plot(app.UIAxes, H2XDATA, H2YDATA,'LineWidth',app.LineSize);
                        
                        plotV1.Color = app.BoxColor;
                        plotV2.Color = app.BoxColor;
                        plotH1.Color = app.BoxColor;
                        plotH2.Color = app.BoxColor;
                        
                        OutputC = OutputA(2:end,:);
                        
                        if size(OutputC,1) > 0
                            for i = 1:size(OutputC, 1)
                                
                                valueXTrue = OutputC(i,1);
                                valueYTrue = OutputC(i,2);
                    
                                XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                                XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                                
                                YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                                YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                                
                                XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                                YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
            
                                pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                        
                    
                            end
                        end
                        
                        app.UITable.Data = OutputC;
                        
                        app.TextArea.Value = 'row deleted';
                        
                    else
                        app.TextArea.Value = 'x value must be in the table!';
                    end
                else
                    app.TextArea.Value = 'x value must be numeric!';
                end
            else
                app.TextArea.Value = 'row deletion canceled';
            end


        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            global D
            global OutputC
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            
            app.UIAxes.CLimMode = 'auto';
              
            Dmax = max(max(D));
            Dmin = min(min(D));
            
            if D(1,1) == Dmax
               D(1,1) = 0.9 * Dmax;
            else
                if Dmin < 0
                    D(end,end) = 1.1*Dmin;
                elseif Dmin == 0
                    D(end,end) = -0.1*Dmax;
                else
                    D(end,end) = 0.9*Dmin;
                end
            end
            
            V1XDATA = plotV1.XData;
            V2XDATA = plotV2.XData;
            V1YDATA = plotV1.YData;
            V2YDATA = plotV2.YData;
            H1XDATA = plotH1.XData;
            H2XDATA = plotH2.XData;
            H1YDATA = plotH1.YData;
            H2YDATA = plotH2.YData;
            
            cla(app.UIAxes)
            image(app.UIAxes, D, 'CDataMapping', 'scaled');
            colormap(app.UIAxes,'jet');
            
            plotV1 = plot(app.UIAxes, V1XDATA, V1YDATA,'LineWidth',app.LineSize);
            plotV2 = plot(app.UIAxes, V2XDATA, V2YDATA,'LineWidth',app.LineSize);
            plotH1 = plot(app.UIAxes, H1XDATA, H1YDATA,'LineWidth',app.LineSize);
            plotH2 = plot(app.UIAxes, H2XDATA, H2YDATA,'LineWidth',app.LineSize);
            
            plotV1.Color = app.BoxColor;
            plotV2.Color = app.BoxColor;
            plotH1.Color = app.BoxColor;
            plotH2.Color = app.BoxColor;
            
            app.CLimEditField.Value = app.UIAxes.CLim(1);
            app.CLim2EditField.Value = app.UIAxes.CLim(2);
            
            if size(OutputC,1) > 0
                for i = 1:size(OutputC, 1)
                    
                    valueXTrue = OutputC(i,1);
                    valueYTrue = OutputC(i,2);
        
                    XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                    XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                    
                    YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                    YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                    
                    XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                    YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
            
                    pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
            
        
                end
            end
            
        end

        % Button pushed function: CButton_2
        function CButton_2Pushed(app, event)
            global D
            global OutputC
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            
            app.UIAxes.CLimMode = 'auto';
               
            Dmax = max(max(D));
            Dmin = min(min(D));
            
            if D(end,end) == Dmin
                D(end,end) = 0.9 * Dmin;
            else
                D(1,1) = 1.1 * Dmax;
            end
            
            V1XDATA = plotV1.XData;
            V2XDATA = plotV2.XData;
            V1YDATA = plotV1.YData;
            V2YDATA = plotV2.YData;
            H1XDATA = plotH1.XData;
            H2XDATA = plotH2.XData;
            H1YDATA = plotH1.YData;
            H2YDATA = plotH2.YData;
            
            cla(app.UIAxes)
            image(app.UIAxes, D, 'CDataMapping', 'scaled');
            colormap(app.UIAxes,'jet');
            
            plotV1 = plot(app.UIAxes, V1XDATA, V1YDATA,'LineWidth',app.LineSize);
            plotV2 = plot(app.UIAxes, V2XDATA, V2YDATA,'LineWidth',app.LineSize);
            plotH1 = plot(app.UIAxes, H1XDATA, H1YDATA,'LineWidth',app.LineSize);
            plotH2 = plot(app.UIAxes, H2XDATA, H2YDATA,'LineWidth',app.LineSize);
            
            plotV1.Color = app.BoxColor;
            plotV2.Color = app.BoxColor;
            plotH1.Color = app.BoxColor;
            plotH2.Color = app.BoxColor;
            
            app.CLimEditField.Value = app.UIAxes.CLim(1);
            app.CLim2EditField.Value = app.UIAxes.CLim(2);
            
            if size(OutputC,1) > 0
                for i = 1:size(OutputC, 1)
                    
                    valueXTrue = OutputC(i,1);
                    valueYTrue = OutputC(i,2);
        
                    XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                    XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                    
                    YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                    YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                    
                    XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                    YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
            
                    pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
                    pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                    pt.Color = app.BoxColor;
            
        
                end
            end
            
        end

        % Button pushed function: SetlinewidthButton
        function SetlinewidthButtonPushed(app, event)
            global D
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            global OutputC
            
            options.Resize='on';
            options.Interpreter='tex';
                    
            answer = inputdlg(['\fontsize{10.5} (current size is ' num2str(app.LineSize) ')'],...
                            'Width of box lines?',[1 50],{''},options);
            
            if size(answer,1) > 0
                
                PrevSize = app.LineSize;
                app.LineSize = str2double(answer{1});
                
                if isnan(app.LineSize) == 0
                    
                    if app.LineSize > 0
                
                        V1XDATA = plotV1.XData;
                        V2XDATA = plotV2.XData;
                        V1YDATA = plotV1.YData;
                        V2YDATA = plotV2.YData;
                        H1XDATA = plotH1.XData;
                        H2XDATA = plotH2.XData;
                        H1YDATA = plotH1.YData;
                        H2YDATA = plotH2.YData;
                        
                        cla(app.UIAxes)
                        image(app.UIAxes, D, 'CDataMapping', 'scaled');
                        colormap(app.UIAxes,'jet');
                        
                        plotV1 = plot(app.UIAxes, V1XDATA, V1YDATA,'LineWidth',app.LineSize);
                        plotV2 = plot(app.UIAxes, V2XDATA, V2YDATA,'LineWidth',app.LineSize);
                        plotH1 = plot(app.UIAxes, H1XDATA, H1YDATA,'LineWidth',app.LineSize);
                        plotH2 = plot(app.UIAxes, H2XDATA, H2YDATA,'LineWidth',app.LineSize);
                        
                        plotV1.Color = app.BoxColor;
                        plotV2.Color = app.BoxColor;
                        plotH1.Color = app.BoxColor;
                        plotH2.Color = app.BoxColor;
            
                        if size(OutputC,1) > 0
                            for i = 1:size(OutputC, 1)
                                
                                valueXTrue = OutputC(i,1);
                                valueYTrue = OutputC(i,2);
                    
                                XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                                XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                                
                                YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                                YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                                
                                XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                                YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
            
                                pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                        
                    
                            end
                        end
                        
                        app.TextArea.Value = '';
                    else
                        app.TextArea.Value = 'LineSize must be greater than zero!';
                        app.CircleSize = PrevSize;
                    end
                    
                else
                    app.TextArea.Value = 'LineSize must be numeric!';
                    app.CircleSize = PrevSize;
                end
            else
                app.TextArea.Value = 'No line size selected!';
            end
        end

        % Callback function
        function VppxrangeButtonPushed(app, event)
                         
        end

        % Value changed function: CLimEditField
        function CLimEditFieldValueChanged(app, event)
            value = app.CLimEditField.Value;
            
            C2 = app.UIAxes.CLim(2);
            app.UIAxes.CLim = [value C2];
            
        end

        % Value changed function: CLim2EditField
        function CLim2EditFieldValueChanged(app, event)
            value = app.CLim2EditField.Value;
            
            C1 = app.UIAxes.CLim(1);
            app.UIAxes.CLim = [C1 value];
            
        end

        % Button pushed function: CresetButton
        function CresetButtonPushed(app, event)
            app.UIAxes.CLim = app.CLimStart;
            app.CLimEditField.Value = app.UIAxes.CLim(1);
            app.CLim2EditField.Value = app.UIAxes.CLim(2);
            
        end

        % Button pushed function: SetboxsizeButton
        function SetboxsizeButtonPushed(app, event)
            global D
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            global OutputC
            global valueXTrue
            global valueYTrue
            
            Xmax = size(D,2);
            Ymax = size(D,1);
            
            options.Resize='on';
            options.Interpreter='tex';
            
            out_1 = inputdlg({'\fontsize{10} \fontname{arial} x pixels', '\fontsize{10} \fontname{arial} y pixels'}, ...
                    'Size of box?', [1 50; 1 50], {'',''}, options);
            
            if size(out_1,1) ~= 0
                
                xSize = str2double(out_1{1}); 
                ySize = str2double(out_1{2}); 
                
                if ~isnan(xSize) && ~isnan(ySize)
                    
                    if xSize < size(D,2) && ySize < size(D,1)
                        
                        app.XBoxSize = xSize;
                        app.YBoxSize = ySize;
                        
                        app.xSlider.Limits = [round(app.XBoxSize/2), round(Xmax-app.XBoxSize/2)];
                        app.ySlider.Limits = [round(app.YBoxSize/2), round(Ymax-app.YBoxSize/2)];
                        app.xSlider.MajorTicks = round(linspace(app.XBoxSize, Xmax-app.XBoxSize,5));
                        app.xSlider.MinorTicks = round(linspace(app.XBoxSize, Xmax-app.XBoxSize,25));
                        app.ySlider.MajorTicks = round(linspace(app.YBoxSize, Ymax-app.YBoxSize,5));
                        app.ySlider.MinorTicks = round(linspace(app.YBoxSize, Ymax-app.YBoxSize,25));
                        
                        app.BoxsizexEditField.Value = app.XBoxSize;
                        app.BoxsizeyEditField.Value = app.YBoxSize;
                        
                        cla(app.UIAxes)
                        image(app.UIAxes, D, 'CDataMapping', 'scaled');
                        colormap(app.UIAxes,'jet');
                        
                        if valueXTrue ~= 0
                            VX1 = round(valueXTrue - app.XBoxSize / 2);
                            VX2 = round(valueXTrue + app.XBoxSize / 2);
                        else
                            VX1 = round(Xmax / 2 - app.XBoxSize / 2);
                            VX2 = round(Xmax / 2 + app.XBoxSize / 2);
                        end
                        
                        if valueYTrue ~= 0
                            VY1 = round(valueYTrue - app.YBoxSize / 2);
                            VY2 = round(valueYTrue + app.YBoxSize / 2);
                        else                            
                            VY1 = round(Ymax / 2 - app.YBoxSize / 2);
                            VY2 = round(Ymax / 2 + app.YBoxSize / 2);
                        end
                        
                        xr1 = [VX1, VX1]; 
                        xr2 = [VX2, VX2];
                        xrV = [VX1, VX2];
                        
                        yr1 = [VY1, VY1];
                        yr2 = [VY2, VY2];
                        yrV = [VY1, VY2];             
                        
                        plotV1 = plot(app.UIAxes, xr1,yrV,'LineWidth',app.LineSize);
                        plotV2 = plot(app.UIAxes, xr2,yrV,'LineWidth',app.LineSize);
                        plotH1 = plot(app.UIAxes, xrV,yr1,'LineWidth',app.LineSize);
                        plotH2 = plot(app.UIAxes, xrV,yr2,'LineWidth',app.LineSize);
                        
                        plotV1.Color = app.BoxColor;
                        plotV2.Color = app.BoxColor;
                        plotH1.Color = app.BoxColor;
                        plotH2.Color = app.BoxColor;
            
                        
                        if size(OutputC,1) > 0
                            for i = 1:size(OutputC, 1)
                                
                                valueXTrue = OutputC(i,1);
                                valueYTrue = OutputC(i,2);
                    
                                XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                                XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                                
                                YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                                YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                                
                                XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                                YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
            
                                pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                                pt.Color = app.BoxColor;
                                
                    
                            end
                        end
                        
                    else
                        app.TextArea.Value = 'Box must fit in window!';
                    end
                    
                else 
                    app.TextArea.Value = 'Box size must be numeric!';
                end
                    
            else 
                app.TextArea.Value = 'No box size selected';
            end
                        
                        
        end

        % Selection changed function: BoxcolorButtonGroup
        function BoxcolorButtonGroupSelectionChanged(app, event)
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            global D
            global OutputC
            
            selectedButton = app.BoxcolorButtonGroup.SelectedObject;
            
            if app.WhiteButton.Value
                
                app.BoxColor = [1 1 1];
                
                V1XDATA = plotV1.XData;
                V2XDATA = plotV2.XData;
                V1YDATA = plotV1.YData;
                V2YDATA = plotV2.YData;
                H1XDATA = plotH1.XData;
                H2XDATA = plotH2.XData;
                H1YDATA = plotH1.YData;
                H2YDATA = plotH2.YData;
                
                cla(app.UIAxes)
                image(app.UIAxes, D, 'CDataMapping', 'scaled');
                colormap(app.UIAxes,'jet');
                
                plotV1 = plot(app.UIAxes, V1XDATA, V1YDATA,'LineWidth',app.LineSize);
                plotV2 = plot(app.UIAxes, V2XDATA, V2YDATA,'LineWidth',app.LineSize);
                plotH1 = plot(app.UIAxes, H1XDATA, H1YDATA,'LineWidth',app.LineSize);
                plotH2 = plot(app.UIAxes, H2XDATA, H2YDATA,'LineWidth',app.LineSize);
                
                plotV1.Color = app.BoxColor;
                plotV2.Color = app.BoxColor;
                plotH1.Color = app.BoxColor;
                plotH2.Color = app.BoxColor;
    
                if size(OutputC,1) > 0
                    for i = 1:size(OutputC, 1)
                        
                        valueXTrue = OutputC(i,1);
                        valueYTrue = OutputC(i,2);
            
                        XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                        XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                        
                        YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                        YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                        
                        XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                        YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
    
                        pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                        pt.Color = app.BoxColor;
                        pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                        pt.Color = app.BoxColor;
                        pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                        pt.Color = app.BoxColor;
                        pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                        pt.Color = app.BoxColor;
                
            
                    end
                end
                        
            elseif app.BlackButton.Value
                
                app.BoxColor = [0 0 0];
                
                V1XDATA = plotV1.XData;
                V2XDATA = plotV2.XData;
                V1YDATA = plotV1.YData;
                V2YDATA = plotV2.YData;
                H1XDATA = plotH1.XData;
                H2XDATA = plotH2.XData;
                H1YDATA = plotH1.YData;
                H2YDATA = plotH2.YData;
                
                cla(app.UIAxes)
                image(app.UIAxes, D, 'CDataMapping', 'scaled');
                colormap(app.UIAxes,'jet');
                
                plotV1 = plot(app.UIAxes, V1XDATA, V1YDATA,'LineWidth',app.LineSize);
                plotV2 = plot(app.UIAxes, V2XDATA, V2YDATA,'LineWidth',app.LineSize);
                plotH1 = plot(app.UIAxes, H1XDATA, H1YDATA,'LineWidth',app.LineSize);
                plotH2 = plot(app.UIAxes, H2XDATA, H2YDATA,'LineWidth',app.LineSize);
                
                plotV1.Color = app.BoxColor;
                plotV2.Color = app.BoxColor;
                plotH1.Color = app.BoxColor;
                plotH2.Color = app.BoxColor;
    
                if size(OutputC,1) > 0
                    for i = 1:size(OutputC, 1)
                        
                        valueXTrue = OutputC(i,1);
                        valueYTrue = OutputC(i,2);
            
                        XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                        XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                        
                        YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                        YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                        
                        XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                        YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
    
                        pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                        pt.Color = app.BoxColor;
                        pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                        pt.Color = app.BoxColor;
                        pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                        pt.Color = app.BoxColor;
                        pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                        pt.Color = app.BoxColor;
                
            
                    end
                end
            end
        end

        % Value changed function: BoxsizexEditField
        function BoxsizexEditFieldValueChanged(app, event)
            global D
            global valueXTrue
            global valueYTrue
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            global OutputC
            
            value = app.BoxsizexEditField.Value;
            
            if isnumeric(value)
                
                if value <= size(D,2)
                    
                    app.XBoxSize = value;
                    
                    app.xSlider.Limits = [round(app.XBoxSize/2), round(Xmax-app.XBoxSize/2)];
                    app.xSlider.MajorTicks = round(linspace(app.XBoxSize, Xmax-app.XBoxSize,5));
                    app.xSlider.MinorTicks = round(linspace(app.XBoxSize, Xmax-app.XBoxSize,25));
                    
                    app.BoxsizexEditField.Value = app.XBoxSize;
                    app.BoxsizeyEditField.Value = app.YBoxSize;
                    
                    cla(app.UIAxes)
                    image(app.UIAxes, D, 'CDataMapping', 'scaled');
                    colormap(app.UIAxes,'jet');
                    
                    if valueXTrue ~= 0
                        VX1 = round(valueXTrue - app.XBoxSize / 2);
                        VX2 = round(valueXTrue + app.XBoxSize / 2);
                    else
                        VX1 = round(Xmax / 2 - app.XBoxSize / 2);
                        VX2 = round(Xmax / 2 + app.XBoxSize / 2);
                    end
                    
                    if valueYTrue ~= 0
                        VY1 = round(valueYTrue - app.YBoxSize / 2);
                        VY2 = round(valueYTrue + app.YBoxSize / 2);
                    else                            
                        VY1 = round(Ymax / 2 - app.YBoxSize / 2);
                        VY2 = round(Ymax / 2 + app.YBoxSize / 2);
                    end
                    
                    xr1 = [VX1, VX1]; 
                    xr2 = [VX2, VX2];
                    xrV = [VX1, VX2];
                    
                    yr1 = [VY1, VY1];
                    yr2 = [VY2, VY2];
                    yrV = [VY1, VY2];             
                    
                    plotV1 = plot(app.UIAxes, xr1,yrV,'LineWidth',app.LineSize);
                    plotV2 = plot(app.UIAxes, xr2,yrV,'LineWidth',app.LineSize);
                    plotH1 = plot(app.UIAxes, xrV,yr1,'LineWidth',app.LineSize);
                    plotH2 = plot(app.UIAxes, xrV,yr2,'LineWidth',app.LineSize);
                    
                    plotV1.Color = app.BoxColor;
                    plotV2.Color = app.BoxColor;
                    plotH1.Color = app.BoxColor;
                    plotH2.Color = app.BoxColor;
        
                    
                    if size(OutputC,1) > 0
                        for i = 1:size(OutputC, 1)
                            
                            valueXTrue = OutputC(i,1);
                            valueYTrue = OutputC(i,2);
                
                            XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                            XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                            
                            YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                            YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                            
                            XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                            YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
        
                            pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                            pt.Color = app.BoxColor;
                            pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                            pt.Color = app.BoxColor;
                            pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                            pt.Color = app.BoxColor;
                            pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                            pt.Color = app.BoxColor;
                    
                
                        end
                    end
                    
                else
                    app.TextArea.Value = 'Box size must fit in window!';
                end
                
            else
                app.TextArea.Value = 'Box size must be numeric!';
            end
            
        end

        % Value changed function: BoxsizeyEditField
        function BoxsizeyEditFieldValueChanged(app, event)
            global D
            global valueXTrue
            global valueYTrue
            global plotV1
            global plotV2
            global plotH1
            global plotH2
            global OutputC
            
            value = app.BoxsizeyEditField.Value;
            
            if isnumeric(value)
                
                if value <= size(D,1)
                    
                    app.YBoxSize = value;

                    app.ySlider.Limits = [round(app.YBoxSize/2), round(Ymax-app.YBoxSize/2)];
                    app.ySlider.MajorTicks = round(linspace(app.YBoxSize, Ymax-app.YBoxSize,5));
                    app.ySlider.MinorTicks = round(linspace(app.YBoxSize, Ymax-app.YBoxSize,25));
                    
                    app.BoxsizexEditField.Value = app.XBoxSize;
                    app.BoxsizeyEditField.Value = app.YBoxSize;
                    
                    cla(app.UIAxes)
                    image(app.UIAxes, D, 'CDataMapping', 'scaled');
                    colormap(app.UIAxes,'jet');
                    
                    if valueXTrue ~= 0
                        VX1 = round(valueXTrue - app.XBoxSize / 2);
                        VX2 = round(valueXTrue + app.XBoxSize / 2);
                    else
                        VX1 = round(Xmax / 2 - app.XBoxSize / 2);
                        VX2 = round(Xmax / 2 + app.XBoxSize / 2);
                    end
                    
                    if valueYTrue ~= 0
                        VY1 = round(valueYTrue - app.YBoxSize / 2);
                        VY2 = round(valueYTrue + app.YBoxSize / 2);
                    else                            
                        VY1 = round(Ymax / 2 - app.YBoxSize / 2);
                        VY2 = round(Ymax / 2 + app.YBoxSize / 2);
                    end
                    
                    xr1 = [VX1, VX1]; 
                    xr2 = [VX2, VX2];
                    xrV = [VX1, VX2];
                    
                    yr1 = [VY1, VY1];
                    yr2 = [VY2, VY2];
                    yrV = [VY1, VY2];             
                    
                    plotV1 = plot(app.UIAxes, xr1,yrV,'LineWidth',app.LineSize);
                    plotV2 = plot(app.UIAxes, xr2,yrV,'LineWidth',app.LineSize);
                    plotH1 = plot(app.UIAxes, xrV,yr1,'LineWidth',app.LineSize);
                    plotH2 = plot(app.UIAxes, xrV,yr2,'LineWidth',app.LineSize);
                    
                    plotV1.Color = app.BoxColor;
                    plotV2.Color = app.BoxColor;
                    plotH1.Color = app.BoxColor;
                    plotH2.Color = app.BoxColor;
        
                    
                    if size(OutputC,1) > 0
                        for i = 1:size(OutputC, 1)
                            
                            valueXTrue = OutputC(i,1);
                            valueYTrue = OutputC(i,2);
                
                            XS1 = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue - app.XBoxSize / 2)];
                            XS2 = [round(valueXTrue + app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                            
                            YS1 = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue - app.YBoxSize / 2)];
                            YS2 = [round(valueYTrue + app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
                            
                            XR = [round(valueXTrue - app.XBoxSize / 2), round(valueXTrue + app.XBoxSize / 2)];
                            YR = [round(valueYTrue - app.YBoxSize / 2), round(valueYTrue + app.YBoxSize / 2)];
        
                            pt = plot(app.UIAxes, XS1, YR, 'LineWidth', app.LineSize2);
                            pt.Color = app.BoxColor;
                            pt = plot(app.UIAxes, XS2, YR, 'LineWidth', app.LineSize2);
                            pt.Color = app.BoxColor;
                            pt = plot(app.UIAxes, XR, YS1, 'LineWidth', app.LineSize2);
                            pt.Color = app.BoxColor;
                            pt = plot(app.UIAxes, XR, YS2, 'LineWidth', app.LineSize2);
                            pt.Color = app.BoxColor;
                    
                
                        end
                    end
                    
                else
                    app.TextArea.Value = 'Box size must fit in window!';
                end
                
            else
                app.TextArea.Value = 'Box size must be numeric!';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddxyvaluepairButton = uibutton(app.UIFigure, 'push');
            app.AddxyvaluepairButton.ButtonPushedFcn = createCallbackFcn(app, @AddxyvaluepairButtonPushed, true);
            app.AddxyvaluepairButton.Text = 'Add (x,y) value pair';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.MajorTicks = [0 20 40 60 80 100];
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.MajorTicks = [0 20 40 60 80 100];
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {};
            app.SavearraytofileButton = uibutton(app.UIFigure, 'push');
            app.SavearraytofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavearraytofileButtonPushed, true);
            app.SavearraytofileButton.Text = 'Save array to file';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.ImportlocationarrayButton = uibutton(app.UIFigure, 'push');
            app.ImportlocationarrayButton.ButtonPushedFcn = createCallbackFcn(app, @ImportlocationarrayButtonPushed, true);
            app.ImportlocationarrayButton.Text = 'Import location array';
            app.DeleterowButton = uibutton(app.UIFigure, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = 'Delete row';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C+';
            app.CButton_2 = uibutton(app.UIFigure, 'push');
            app.CButton_2.ButtonPushedFcn = createCallbackFcn(app, @CButton_2Pushed, true);
            app.CButton_2.Text = 'C-';
            app.SetlinewidthButton = uibutton(app.UIFigure, 'push');
            app.SetlinewidthButton.ButtonPushedFcn = createCallbackFcn(app, @SetlinewidthButtonPushed, true);
            app.SetlinewidthButton.Text = 'Set linewidth';
            app.ImportdataButton = uibutton(app.UIFigure, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.CLimEditFieldLabel = uilabel(app.UIFigure);
            app.CLimEditFieldLabel.Text = 'CLim';
            app.CLimEditField = uieditfield(app.UIFigure, 'numeric');
            app.CLimEditField.ValueChangedFcn = createCallbackFcn(app, @CLimEditFieldValueChanged, true);
            app.CLim2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CLim2EditField.ValueChangedFcn = createCallbackFcn(app, @CLim2EditFieldValueChanged, true);
            app.CresetButton = uibutton(app.UIFigure, 'push');
            app.CresetButton.ButtonPushedFcn = createCallbackFcn(app, @CresetButtonPushed, true);
            app.CresetButton.Text = 'C reset';
            app.SetboxsizeButton = uibutton(app.UIFigure, 'push');
            app.SetboxsizeButton.ButtonPushedFcn = createCallbackFcn(app, @SetboxsizeButtonPushed, true);
            app.SetboxsizeButton.Text = 'Set box size';
            app.BoxsizexEditFieldLabel = uilabel(app.UIFigure);
            app.BoxsizexEditFieldLabel.Text = 'Box size x';
            app.BoxsizexEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoxsizexEditField.ValueChangedFcn = createCallbackFcn(app, @BoxsizexEditFieldValueChanged, true);
            app.BoxsizeyEditFieldLabel = uilabel(app.UIFigure);
            app.BoxsizeyEditFieldLabel.Text = 'Box size y';
            app.BoxsizeyEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoxsizeyEditField.ValueChangedFcn = createCallbackFcn(app, @BoxsizeyEditFieldValueChanged, true);
            app.BoxcolorButtonGroup = uibuttongroup(app.UIFigure);
            app.BoxcolorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoxcolorButtonGroupSelectionChanged, true);
            app.BoxcolorButtonGroup.Title = 'Box color';
            app.WhiteButton = uiradiobutton(app.BoxcolorButtonGroup);
            app.WhiteButton.Text = 'White';
            app.WhiteButton.Value = true;
            app.BlackButton = uiradiobutton(app.BoxcolorButtonGroup);
            app.BlackButton.Text = 'Black';
        end
    end

    methods (Access = public)

        % Construct app
        function app = background_selection_app_1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end